classdef Live_Signal_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        graph                   matlab.ui.control.UIAxes
        VoltageButton           matlab.ui.control.Button
        CurrentButton           matlab.ui.control.Button
        FFTButton               matlab.ui.control.Button
        VandIButton             matlab.ui.control.Button
        VrmsEditFieldLabel      matlab.ui.control.Label
        VrmsEditField           matlab.ui.control.NumericEditField
        IrmsEditFieldLabel      matlab.ui.control.Label
        IrmsEditField           matlab.ui.control.NumericEditField
        PFEditFieldLabel        matlab.ui.control.Label
        PFEditField             matlab.ui.control.NumericEditField
        IppEditFieldLabel       matlab.ui.control.Label
        IppEditField            matlab.ui.control.NumericEditField
        VppEditFieldLabel       matlab.ui.control.Label
        VppEditField            matlab.ui.control.NumericEditField
        TimeScaleKnobLabel      matlab.ui.control.Label
        TimeKnob                matlab.ui.control.Knob
        VoltageScaleKnobLabel   matlab.ui.control.Label
        VoltageKnob             matlab.ui.control.Knob
        YCurrentScaleKnobLabel  matlab.ui.control.Label
        CurrentKnob             matlab.ui.control.Knob
        ChannelEditFieldLabel   matlab.ui.control.Label
        ChannelEditField        matlab.ui.control.NumericEditField
        THDEditFieldLabel       matlab.ui.control.Label
        THDEditField            matlab.ui.control.NumericEditField
        RangeSwitchLabel        matlab.ui.control.Label
        RangeSwitch             matlab.ui.control.Switch
    end


    properties (Access = public)
           fs = 48000;
           t  = 0;
           yv = 0;
           yi = 0;
           xlimit  = 0.08;
           ylimitV = 400;
           ylimitI = 0.1;
    end


    methods (Access = private)

        % Callback function: CurrentButton, RangeSwitch
        function CurrentButtonPushed(app, event)
            app.t = (1/app.fs):(1/app.fs):(0.2);
            recorderi = audiorecorder(app.fs,16,1,1);
            
            for k = 1:inf
                
                recordblocking(recorderi, 0.2);
                
                app.yi = getaudiodata(recorderi);
                app.yi = app.yi-(sum(app.yi)/length(app.yi));
                
                if app.RangeSwitch.Value == '1 A'
                    app.yi = app.yi*1.34;
                elseif app.RangeSwitch.Value == '5 A'
                    app.yi = app.yi*2500;
                end
                
                plot(app.graph, app.t, app.yi, 'y');
                ax = app.graph;
                ax.XLim = [0 app.xlimit];
                ax.YLim = [-app.ylimitI app.ylimitI];
                
                ipp = max(app.yi)- min(app.yi);
                app.IppEditField.Value = ipp;
                
                irms = sqrt(sum(app.yi.^2)/length(app.yi));
                app.IrmsEditField.Value = irms;
                                refreshdata
            end
        end

        % Value changing function: CurrentKnob
        function CurrentKnobValueChanging(app, event)
           
            app.ylimitI = event.Value;
            
        end

        % Value changing function: TimeKnob
        function TimeKnobValueChanging(app, event)
            
            app.xlimit = event.Value;
            
        end

        % Button pushed function: VandIButton
        function VandIButtonPushed(app, event)
            app.t = (1/app.fs):(1/app.fs):0.2;
            
            recorderv = audiorecorder(app.fs,16,1,2);
            recorderi = audiorecorder(app.fs,16,1,1); 
            
            for k = 1:inf
                recordblocking(recorderv, 0.2);
                app.yv = getaudiodata(recorderv);
                app.yv = app.yv-(sum(app.yv)/length(app.yv));
                app.yv = app.yv*957;
                
                recordblocking(recorderi, 0.2);
                app.yi = getaudiodata(recorderi);
                app.yi = app.yi-(sum(app.yi)/length(app.yi));
                app.yi = app.yi*314.285;
                
                ax = app.graph;
                ax.Box = 'off';
                ax.XLim = [0 app.xlimit];
                ax.YLim = [-app.ylimitV app.ylimitV];
                
                plot(app.graph,app.t,app.yv,'b');
                hold(app.graph);            
                plot(app.graph,app.t,app.yi,'y');
                hold(app.graph);
                
                dotProduct = dot(app.yv,app.yi);
                normProduct = (norm(app.yv)*norm(app.yi));
                pf = (dotProduct/normProduct);
                app.PFEditField.Value = abs(pf); 
                
                refreshdata
            end           
        end

        % Button pushed function: VoltageButton
        function VoltageButtonPushed(app, event)
            
            app.t = (1/app.fs):(1/app.fs):0.2;
            recorderv = audiorecorder(app.fs,16,1,2);
            
            for k = 1:inf
            
                recordblocking(recorderv, 0.2);
                
                app.yv = getaudiodata(recorderv); 
                app.yv = app.yv-(sum(app.yv)/length(app.yv));
                app.yv = app.yv*957;
                
                plot(app.graph,app.t,app.yv,'y');
                
                ax = app.graph;
                ax.XLim = [0 app.xlimit];
                ax.YLim = [-app.ylimitV app.ylimitV];
                
                vrms = sqrt(sum(app.yv.^2)/length(app.yv));
                app.VrmsEditField.Value = vrms;
                
                vpp = max(app.yv)- min(app.yv);
                app.VppEditField.Value = vpp;
                
                refreshdata
            end           
        end

        % Value changing function: VoltageKnob
        function VoltageKnobValueChanging(app, event)
            app.ylimitV = event.Value;
        end

        % Callback function: ChannelEditField, FFTButton, THDEditField
        function fftButtonPushed(app, event)
            app.t = (1/app.fs):(1/app.fs):0.2;
            recorderfft = audiorecorder(48000,16,1,app.ChannelEditField.Value);
            
            win_size = 0.02*app.fs;
            frame_rate = 0.01*app.fs;
            win = window(@hamming,win_size);
            
            for k = 1:inf
                
                recordblocking(recorderfft, 0.2);
                y = getaudiodata(recorderfft); 
                y = y-(sum(y)/length(y));
                
                    if app.ChannelEditField.Value == '1' && app.RangeSwitch.Value == '1 A'
                        y = y*1.34;
                    elseif app.ChannelEditField.Value == '1' && app.RangeSwitch.Value == '5 A'                       
                        y = y*314.285;
                    else
                        y = y*896;
                    end
                
                B = buffer(y , win_size , frame_rate);
                [m, n] = size(B);
                
                for i = 1:n
                    BB = B(:,i).*win;    
                    BF = abs(fft(BB))/length(BB);
                end
                
                N = length(BF);
                f = (0:1/N:1-1/N)*app.fs;
                
                stem(app.graph,f,BF)
                
                ax = app.graph;
                ax.XLim = [0 1000];
                ax.YLim = [0 250];
                
                vp1 = BF(2);
                vp  = max(y);
                app.THDEditField.Value = sqrt(vp^2-vp1^2)/vp1;
                
                refreshdata
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'LIVE SIGNAL ANALYZER')
            xlabel(app.graph, 'Time')
            ylabel(app.graph, 'Amplitude')
            app.VoltageButton = uibutton(app.UIFigure, 'push');
            app.VoltageButton.ButtonPushedFcn = createCallbackFcn(app, @VoltageButtonPushed, true);
            app.VoltageButton.Text = 'Voltage';
            app.CurrentButton = uibutton(app.UIFigure, 'push');
            app.CurrentButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentButtonPushed, true);
            app.CurrentButton.Text = 'Current';
            app.FFTButton = uibutton(app.UIFigure, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @fftButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.VandIButton = uibutton(app.UIFigure, 'push');
            app.VandIButton.ButtonPushedFcn = createCallbackFcn(app, @VandIButtonPushed, true);
            app.VandIButton.Text = 'V and I';
            app.VrmsEditFieldLabel = uilabel(app.UIFigure);
            app.VrmsEditFieldLabel.Text = 'Vrms';
            app.VrmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VrmsEditField.ValueDisplayFormat = '%.2f';
            app.VrmsEditField.Editable = 'off';
            app.IrmsEditFieldLabel = uilabel(app.UIFigure);
            app.IrmsEditFieldLabel.Text = 'Irms';
            app.IrmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IrmsEditField.ValueDisplayFormat = '%.4f';
            app.IrmsEditField.Editable = 'off';
            app.PFEditFieldLabel = uilabel(app.UIFigure);
            app.PFEditFieldLabel.Text = 'PF';
            app.PFEditField = uieditfield(app.UIFigure, 'numeric');
            app.PFEditField.ValueDisplayFormat = '%.3f';
            app.PFEditField.Editable = 'off';
            app.IppEditFieldLabel = uilabel(app.UIFigure);
            app.IppEditFieldLabel.Text = 'Ip-p';
            app.IppEditField = uieditfield(app.UIFigure, 'numeric');
            app.IppEditField.ValueDisplayFormat = '%.4f';
            app.IppEditField.Editable = 'off';
            app.VppEditFieldLabel = uilabel(app.UIFigure);
            app.VppEditFieldLabel.Text = 'Vp-p';
            app.VppEditField = uieditfield(app.UIFigure, 'numeric');
            app.VppEditField.ValueDisplayFormat = '%.2f';
            app.VppEditField.Editable = 'off';
            app.TimeScaleKnobLabel = uilabel(app.UIFigure);
            app.TimeScaleKnobLabel.Text = 'Time Scale';
            app.TimeKnob = uiknob(app.UIFigure, 'continuous');
            app.TimeKnob.Limits = [0.02 0.2];
            app.TimeKnob.MajorTicks = [0.02 0.06 0.1 0.12 0.16 0.2];
            app.TimeKnob.ValueChangingFcn = createCallbackFcn(app, @TimeKnobValueChanging, true);
            app.TimeKnob.MinorTicks = [];
            app.TimeKnob.Value = 0.08;
            app.VoltageScaleKnobLabel = uilabel(app.UIFigure);
            app.VoltageScaleKnobLabel.Text = 'Voltage Scale';
            app.VoltageKnob = uiknob(app.UIFigure, 'continuous');
            app.VoltageKnob.Limits = [0.1 500];
            app.VoltageKnob.MajorTicks = [0.1 100 200 300 400 500];
            app.VoltageKnob.ValueChangingFcn = createCallbackFcn(app, @VoltageKnobValueChanging, true);
            app.VoltageKnob.MinorTicks = [];
            app.VoltageKnob.Value = 400;
            app.YCurrentScaleKnobLabel = uilabel(app.UIFigure);
            app.YCurrentScaleKnobLabel.Text = 'Y - Current Scale';
            app.CurrentKnob = uiknob(app.UIFigure, 'continuous');
            app.CurrentKnob.Limits = [0.1 8];
            app.CurrentKnob.MajorTicks = [0.1 2 4 6 8];
            app.CurrentKnob.ValueChangingFcn = createCallbackFcn(app, @CurrentKnobValueChanging, true);
            app.CurrentKnob.MinorTicks = [];
            app.CurrentKnob.Value = 0.1;
            app.ChannelEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelEditField.ValueChangedFcn = createCallbackFcn(app, @fftButtonPushed, true);
            app.ChannelEditField.Limits = [1 2];
            app.ChannelEditField.ValueDisplayFormat = '%11.1g';
            app.ChannelEditField.Value = 1;
            app.THDEditFieldLabel = uilabel(app.UIFigure);
            app.THDEditFieldLabel.Text = 'THD';
            app.THDEditField = uieditfield(app.UIFigure, 'numeric');
            app.THDEditField.ValueChangedFcn = createCallbackFcn(app, @fftButtonPushed, true);
            app.THDEditField.ValueDisplayFormat = '%.3f';
            app.THDEditField.Editable = 'off';
            app.RangeSwitchLabel = uilabel(app.UIFigure);
            app.RangeSwitchLabel.Text = 'Range';
            app.RangeSwitch = uiswitch(app.UIFigure, 'slider');
            app.RangeSwitch.Items = {'5 A', '1 A'};
            app.RangeSwitch.ValueChangedFcn = createCallbackFcn(app, @CurrentButtonPushed, true);
            app.RangeSwitch.Value = '1 A';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Live_Signal_Analyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end